classdef GUI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        StartData                      matlab.ui.control.Button
        StopDataButton                 matlab.ui.control.StateButton
        YawHeadingDegreeGauge          matlab.ui.control.SemicircularGauge
        YawHeadingdegreeGaugeLabel     matlab.ui.control.Label
        PitchDegreeGauge               matlab.ui.control.SemicircularGauge
        PitchdegreeGaugeLabel          matlab.ui.control.Label
        RollDegreeGauge                matlab.ui.control.SemicircularGauge
        RolldegreeLabel                matlab.ui.control.Label
        WheelSteeringAngleDegreeGauge  matlab.ui.control.SemicircularGauge
        WheelSteeringAngledegreeGaugeLabel  matlab.ui.control.Label
        ThrottleGauge                  matlab.ui.control.LinearGauge
        ThrottleGaugeLabel             matlab.ui.control.Label
        RFWheelRPMGauge                matlab.ui.control.Gauge
        RFWheelRPMLabel                matlab.ui.control.Label
        LFWheelRPMGauge                matlab.ui.control.Gauge
        LFWheelRPMLabel                matlab.ui.control.Label
        LRWheelRPMGauge                matlab.ui.control.Gauge
        LRWheelRPMGaugeLabel           matlab.ui.control.Label
        RRWheelRPMGauge                matlab.ui.control.Gauge
        RRWheelRPMGaugeLabel           matlab.ui.control.Label
        BatteryPlot                    matlab.ui.control.UIAxes
        VelocityPlot                   matlab.ui.control.UIAxes
        GraphsTab                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        WheelSpeedTab                  matlab.ui.container.Tab
        WheelSpeedGraph                matlab.ui.control.UIAxes
        VehicleSpeedTab                matlab.ui.container.Tab
        CellVoltageTab                 matlab.ui.container.Tab
        SetupTab                       matlab.ui.container.Tab
        GPSFixLamp                     matlab.ui.control.Lamp
        GPSFixLampLabel                matlab.ui.control.Label
        SSHCarNameEditField            matlab.ui.control.NumericEditField
        SSHCarNameEditFieldLabel       matlab.ui.control.Label
        SSHPortNumberEditField         matlab.ui.control.NumericEditField
        SSHPortNumberEditFieldLabel    matlab.ui.control.Label
        SSHPasswordEditField           matlab.ui.control.EditField
        SSHPasswordEditFieldLabel      matlab.ui.control.Label
        SSHAddressEditField            matlab.ui.control.EditField
        SSHAddressEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            %Set up velocity and acceleration plot
            ylabel(app.VelocityPlot, 'Acceleration (m/s^2)', Color = 'black')
            ylim(app.VelocityPlot, [-15,15])
            app.VelocityPlot.YColor = ('magenta');
            yticks(app.VelocityPlot, [-15, -10, -5, 0, 5, 10, 15])
            xlim(app.VelocityPlot, [0, 60])
            

            %Set up battery voltage plot
            %yyaxis(app.BatteryPlot, 'left')
            ylabel(app.BatteryPlot, 'Cell Voltage (V)', Color = 'black')
            ylim(app.BatteryPlot, [0,5])
            xlim(app.BatteryPlot, [0, 60])


        end

        % Button pushed function: StartData
        function StartDataButtonPushed(app, event)
            
            %Initialize a flag variable
            flag = 1;

            %Rename file as needed
            fileName = "../rc-car-daq/DAQ/DAQ.csv"; %Will use DAQ.csv

            %Initialize data table
            %data = table;

            %Read table and determine first time

            data = fopen(fileName);
            TrimmedData = data(end,:);

            %data = readtable(fileName);
            %start_time = index(data.time(end))
            % start_time = length(data.time)-1;
            % old_time_index = length(data.time)-2;
            % current_time_index = start_time;

            %Reset stop button status
            app.StopDataButton.Value = true;

            % Assingment of static variables used for plotting
            RedlineWheelRPM = 2000;
            
            first_loop = true;
            AccelerationTotal = [];
            TimeTotal = [];
            while flag
                
                loop_start = datetime;
                if first_loop
                    start_time = datetime;
                    first_loop = false;
                end

                flag = get(app.StopDataButton, 'value'); %Flag is set to status of the stop button

                %drawnow %Allows time to check the status of the stop button
                % fileID = fopen(fileName);
                % TrimmedData = feof(fileID,-24,'eof');
                % fclose(fileID);

                TrimmedData = readmatrix(fileName,"Range","2A:2W");

                if height(TrimmedData) ~= 1
                    disp(height(TrimmedData))
                    TrimmedData = zeros(1,23);
                    continue
                end

                %Loop to break out of while loop if stop button pressed
                if isequal(flag, 'Off')
                    flag = 0;
                    break
                end
                
                % Variable Assignment
                % data= readtable(fileName);
                    % If this is not fast enough, look at only reading last
                    % few lines and appending to data table

                for i=1:23
                    if isnan(TrimmedData(1,i))
                        TrimmedData(1,i) = 0;
                        disp('NaN')
                    end
                end

                ThrottlePWM = 1500; %TODO ADD TO CSV
                SteeringPWM = (TrimmedData(22));
                LFWheelRPM = (TrimmedData(20));
                RFWheelRPM = TrimmedData(21);
                LRWheelRPM = TrimmedData(18);
                RRWheelRPM = TrimmedData(19);
                Cell0Voltage = TrimmedData(2);
                Cell1Voltage = TrimmedData(3);
                Cell2Voltage = TrimmedData(4);
                Cell3Voltage = TrimmedData(5);
                Cell4Voltage = TrimmedData(6);
                Cell5Voltage = TrimmedData(7);
                RollAngle = TrimmedData(12);
                PitchAngle = TrimmedData(11);
                YawAngle = TrimmedData(13);
                Velocity = TrimmedData(17);
                Acceleration = double(TrimmedData(8))*-1;
                GPSFix = TrimmedData(16);
                Time = TrimmedData(1);
                if abs(double(Acceleration)) > 30
                    continue
                end


                TimeTotal = [TimeTotal; seconds(datetime-start_time)];
                AccelerationTotal = [AccelerationTotal; double(Acceleration)];
                % ThrottlePWM = data.throttle(current_time_index);
                % SteeringPWM = data.steering(current_time_index);
                % LFWheelRPM = data.rpm_lf(current_time_index);
                % RFWheelRPM = data.rpm_rf(current_time_index);
                % LRWheelRPM = data.rpm_lr(current_time_index);
                % RRWheelRPM = data.rpm_rr(current_time_index);
                % Cell0Voltage = data.cell0(current_time_index);
                % Cell1Voltage = data.cell1(current_time_index);
                % Cell2Voltage = data.cell2(current_time_index);
                % Cell3Voltage = data.cell3(current_time_index);
                % Cell4Voltage = data.cell4(current_time_index);
                % Cell5Voltage = data.cell5(current_time_index);
                % RollAngle = data.roll(current_time_index) ;
                % PitchAngle = data.pitch(current_time_index);
                % YawAngle = data.yaw(current_time_index);
                % Velocity = data.velocity(current_time_index);
                % Acceleration = data.acc_x(current_time_index);
                % GPSFix = data.satellites(current_time_index);
                % Time = data.time(current_time_index);


                % Math
                %TotalVoltage = sum([Cell0Voltage Cell1Voltage Cell2Voltage Cell3Voltage Cell4Voltage Cell5Voltage]);
                %SteeringAngle = -1*10^(-10)*SteeringPWM^5+9*10^(-9)*SteeringPWM^4-3*10^(-5)*SteeringPWM^3+0.041*SteeringPWM^2-29.632*SteeringPWM+8365.6; %R2 = 0.9995
                %SteeringAngle = 1*10^(-5)*SteeringPWM^2 + 0.0498*SteeringPWM-104.34; %R2 = 0.9928
                SteeringAngle = 15;
                ThrottlePosition = (ThrottlePWM-1500)/5;

                % Plotting
               
                % %Left Front wheel RPM plotting
                % app.LFWheelRPMGauge.Value = LFWheelRPM;
                % if LFWheelRPM > RedlineWheelRPM
                %     app.LFWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                %     app.LFWheelRPMGauge.ScaleColorLimits = [2000 LFWheelRPM; 0 2000];
                %  else
                %     app.LFWheelRPMGauge.ScaleColors = [0 1 0];
                %     app.LFWheelRPMGauge.ScaleColorLimits = [-1 LFWheelRPM];
                % end
                % 
                % %Right front wheel RPM plotting
                % app.RFWheelRPMGauge.Value = RFWheelRPM;
                % if RFWheelRPM > RedlineWheelRPM
                %     app.RFWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                %     app.RFWheelRPMGauge.ScaleColorLimits = [2000 RFWheelRPM;0 2000];
                % else
                %     app.RFWheelRPMGauge.ScaleColors = [0 1 0];
                %     app.RFWheelRPMGauge.ScaleColorLimits = [-1 RFWheelRPM];
                % end
                % 
                % %Left rear wheel RPM plotting
                % app.LRWheelRPMGauge.Value = LRWheelRPM;
                % if LRWheelRPM > RedlineWheelRPM
                %     app.LRWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                %     app.LRWheelRPMGauge.ScaleColorLimits = [2000 LRWheelRPM;0 2000];
                % else
                %     app.LRWheelRPMGauge.ScaleColors = [0 1 0];
                %     app.LRWheelRPMGauge.ScaleColorLimits = [-1 LRWheelRPM];
                % end
                % 
                % %Right rear wheel RPM plotting
                % app.RRWheelRPMGauge.Value = RRWheelRPM;
                % if RRWheelRPM > RedlineWheelRPM
                %     app.RRWheelRPMGauge.ScaleColors = [1 0 0;0 1 0];
                %     app.RRWheelRPMGauge.ScaleColorLimits = [2000 RRWheelRPM;0 2000];
                % else
                %     app.RRWheelRPMGauge.ScaleColors = [0 1 0];
                %     app.RRWheelRPMGauge.ScaleColorLimits = [-1 RRWheelRPM];
                % end

                %Plot Roll
                app.RollDegreeGauge.Value = RollAngle;
                if RollAngle > 0
                    app.RollDegreeGauge.ScaleColors = [1 1 0];
                    app.RollDegreeGauge.ScaleColorLimits = [0, RollAngle];
                elseif RollAngle < 0
                    app.RollDegreeGauge.ScaleColors = [1 1 0];
                    app.RollDegreeGauge.ScaleColorLimits = [RollAngle, 0];
                end

                %Plot Pitch
                app.PitchDegreeGauge.Value = PitchAngle;
                if PitchAngle > 0
                    app.PitchDegreeGauge.ScaleColors = [1 1 0];
                    app.PitchDegreeGauge.ScaleColorLimits = [0,PitchAngle];
                elseif PitchAngle < 0
                    app.PitchDegreeGauge.ScaleColors = [1 1 0];
                    app.PitchDegreeGauge.ScaleColorLimits = [PitchAngle, 0];
                end
                
                %Plot Steering Angle
                app.WheelSteeringAngleDegreeGauge.Value = SteeringAngle;
                if SteeringAngle > 0
                    app.WheelSteeringAngleDegreeGauge.ScaleColors = [1 1 0];
                    app.WheelSteeringAngleDegreeGauge.ScaleColorLimits = [0, SteeringAngle];
                elseif SteeringAngle < 0
                    app.WheelSteeringAngleDegreeGauge.ScaleColors = [1 1 0];
                    app.WheelSteeringAngleDegreeGauge.ScaleColorLimits = [SteeringAngle, 0];
                end
                
                %Plot Heading
                app.YawHeadingDegreeGauge.Value = YawAngle;
                if YawAngle > 0
                    app.YawHeadingDegreeGauge.ScaleColors = [1 1 0];
                    app.YawHeadingDegreeGauge.ScaleColorLimits = [0, YawAngle];
                elseif YawAngle < 0
                    app.YawHeadingDegreeGauge.ScaleColors = [1 1 0];
                    app.YawHeadingDegreeGauge.ScaleColorLimits = [YawAngle, 0];
                end

                %Plot throttle
                app.ThrottleGauge.Value = ThrottlePosition;
                if ThrottlePosition > 0
                    app.ThrottleGauge.ScaleColors = [0 1 0];
                    app.ThrottleGauge.ScaleColorLimits = [0 ThrottlePosition];
                elseif ThrottlePosition < 0
                    app.ThrottleGauge.ScaleColors = [1 0 0];
                    app.ThrottleGauge.ScaleColorLimits = [ThrottlePosition, 0];
                end

                 % %Plot Battery Voltages
                 % hold(app.BatteryPlot, 'on')
                 % plot(app.BatteryPlot, data.time(1:current_time_index), data.cell0(1:current_time_index), color=[1,0,0])
                 % plot(app.BatteryPlot, data.time(1:current_time_index), data.cell1(1:current_time_index), color=[0 1 0])
                 % plot(app.BatteryPlot, data.time(1:current_time_index), data.cell2(1:current_time_index), color=[0 0 1])
                 % plot(app.BatteryPlot, data.time(1:current_time_index), data.cell3(1:current_time_index), color=[1 1 0])
                 % plot(app.BatteryPlot, data.time(1:current_time_index), data.cell4(1:current_time_index), color=[1 0 1])
                 % plot(app.BatteryPlot, data.time(1:current_time_index), data.cell5(1:current_time_index), color=[0 0 0])
                 % hold(app.BatteryPlot, 'off')
                 % if TotalVoltage >= 19.2
                 %     app.BatteryPlot.Color = '#C7F6C7';
                 % else
                 %     app.BatteryPlot.Color = '#ffa3a6';
                 % end

                 %Plot New Battery Points
                 % battery = animatedline(app.BatteryPlot);
                 % addpoints(battery, data.time(old_time_index:current_time_index), data.cell0(old_time_index:current_time_index))
                 % addpoints(battery, data.time(old_time_index:current_time_index), data.cell1(old_time_index:current_time_index))
                 % addpoints(battery, data.time(old_time_index:current_time_index), data.cell2(old_time_index:current_time_index))
                 % addpoints(battery, data.time(old_time_index:current_time_index), data.cell3(old_time_index:current_time_index))
                 % addpoints(battery, data.time(old_time_index:current_time_index), data.cell4(old_time_index:current_time_index))
                 % addpoints(battery, data.time(old_time_index:current_time_index), data.cell5(old_time_index:current_time_index))
                 % % 

                 %Plot Vehicle velocity and acceleration
                 %yyaxis left
                 % velocityLine = animatedline(app.VelocityPlot);
                 % yyaxis left 
                 % addpoints(velocityLine, data.time(old_time_index:current_time_index), data.velocity(old_time_index:current_time_index) )
                 % 
                 %accLine = animatedline(app.VelocityPlot);
                 
                 %addpoints(accLine, TimeTotal, AccelerationTotal)
                 %yyaxis(app.VelocityPlot, 'right')
                 plot(app.VelocityPlot, TimeTotal, AccelerationTotal, Color= [0 0 1])
                 %yyaxis(app.VelocityPlot, 'left')
                 %plot(app.VelocityPlot, datetime-start_time), data.velocity(1:current_time_index), Color= [1 0 0])
                 %legend(app.VelocityPlot,'Acceleration','Velocity')
                 
                 drawnow

                 
                 RunTime = seconds(datetime - loop_start);
                 
            end
            disp("end data transmission")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'autumn');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.VelocityPlot = uiaxes(app.HomeTab);
            title(app.VelocityPlot, 'Vehicle Velocity and Acceleration')
            xlabel(app.VelocityPlot, 'Time (s)')
            ylabel(app.VelocityPlot, 'Y')
            zlabel(app.VelocityPlot, 'Z')
            app.BatteryPlot = uiaxes(app.HomeTab);
            title(app.BatteryPlot, 'Battery Cell Voltages')
            xlabel(app.BatteryPlot, 'Time (s)')
            ylabel(app.BatteryPlot, 'Voltage')
            zlabel(app.BatteryPlot, 'Z')
            app.BatteryPlot.XLim = [0 60];
            app.BatteryPlot.YLim = [0 5];
            app.BatteryPlot.XTick = [0 10 20 30 40 50 60];
            app.BatteryPlot.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'};
            app.BatteryPlot.YTick = [0 1 2 3 4 5];
            app.BatteryPlot.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.BatteryPlot.ZTick = [0 0.5 1];
            app.RRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.RRWheelRPMGaugeLabel.Interpreter = 'latex';
            app.RRWheelRPMGaugeLabel.Text = 'RR Wheel RPM';
            app.RRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RRWheelRPMGauge.Limits = [0 2500];
            app.RRWheelRPMGauge.MajorTicks = [0 600 1250 1900 2500];
            app.RRWheelRPMGauge.MajorTickLabels = {'0', '600', '1250', '1900', '2500'};
            app.RRWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.LRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.LRWheelRPMGaugeLabel.Interpreter = 'latex';
            app.LRWheelRPMGaugeLabel.Text = 'LR Wheel RPM';
            app.LRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LRWheelRPMGauge.Limits = [0 2500];
            app.LRWheelRPMGauge.MajorTicks = [0 600 1250 1900 2500];
            app.LRWheelRPMGauge.MajorTickLabels = {'0', '600', '1250', '1900', '2500'};
            app.LRWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.LFWheelRPMLabel = uilabel(app.HomeTab);
            app.LFWheelRPMLabel.Interpreter = 'latex';
            app.LFWheelRPMLabel.Text = 'LF Wheel RPM';
            app.LFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LFWheelRPMGauge.Limits = [0 2500];
            app.LFWheelRPMGauge.MajorTicks = [0 600 1250 1900 2500];
            app.LFWheelRPMGauge.MajorTickLabels = {'0', '600', '1250', '1900', '2500'};
            app.LFWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.RFWheelRPMLabel = uilabel(app.HomeTab);
            app.RFWheelRPMLabel.Interpreter = 'latex';
            app.RFWheelRPMLabel.Text = 'RF Wheel RPM';
            app.RFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RFWheelRPMGauge.Limits = [0 2500];
            app.RFWheelRPMGauge.MajorTicks = [0 600 1250 1900 2500];
            app.RFWheelRPMGauge.MajorTickLabels = {'0', '600', '1250', '1900', '2500'};
            app.RFWheelRPMGauge.MinorTicks = [0 100 200 300 400 500 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 2000 2100 2200 2300 2400];
            app.ThrottleGaugeLabel = uilabel(app.HomeTab);
            app.ThrottleGaugeLabel.Interpreter = 'latex';
            app.ThrottleGaugeLabel.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.HomeTab, 'linear');
            app.ThrottleGauge.Limits = [-100 100];
            app.ThrottleGauge.MajorTicks = [-100 -75 -50 -25 0 25 50 75 100];
            app.ThrottleGauge.MajorTickLabels = {'-100%', '-75%', '-50%', '-25%', '0%', '25%', '50%', '75%', '100%'};
            app.ThrottleGauge.MinorTicks = [-100 -87 -75 -62.5 -50 -37.5 -25 -12.5 0 12.5 25 37.5 50 62.5 75 87.5 100];
            app.WheelSteeringAngledegreeGaugeLabel = uilabel(app.HomeTab);
            app.WheelSteeringAngledegreeGaugeLabel.Interpreter = 'latex';
            app.WheelSteeringAngledegreeGaugeLabel.Text = 'Wheel Steering Angle (\degree)';
            app.WheelSteeringAngleDegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.WheelSteeringAngleDegreeGauge.Limits = [-45 45];
            app.WheelSteeringAngleDegreeGauge.MajorTicks = [-45 -30 -15 0 15 30 45];
            app.WheelSteeringAngleDegreeGauge.MajorTickLabels = {'-45', '-30', '-15', '0', '15', '30', '45'};
            app.WheelSteeringAngleDegreeGauge.MinorTicks = [-40 -35 -25 -20 -10 -5 5 10 20 25 35 40];
            app.RolldegreeLabel = uilabel(app.HomeTab);
            app.RolldegreeLabel.Interpreter = 'latex';
            app.RolldegreeLabel.Text = 'Roll (\degree)';
            app.RollDegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.RollDegreeGauge.Limits = [-20 20];
            app.RollDegreeGauge.MajorTicks = [-20 -10 0 10 20];
            app.RollDegreeGauge.MajorTickLabels = {'-20', '-10', '0', '10', '20'};
            app.RollDegreeGauge.MinorTicks = [-15 -5 5 15];
            app.PitchdegreeGaugeLabel = uilabel(app.HomeTab);
            app.PitchdegreeGaugeLabel.Interpreter = 'latex';
            app.PitchdegreeGaugeLabel.Text = 'Pitch (\degree)';
            app.PitchDegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.PitchDegreeGauge.Limits = [-20 20];
            app.PitchDegreeGauge.MajorTicks = [-20 -10 0 10 20];
            app.PitchDegreeGauge.MajorTickLabels = {'-20', '-10', '0', '10', '20'};
            app.PitchDegreeGauge.MinorTicks = [-15 -5 5 15];
            app.YawHeadingdegreeGaugeLabel = uilabel(app.HomeTab);
            app.YawHeadingdegreeGaugeLabel.Interpreter = 'latex';
            app.YawHeadingdegreeGaugeLabel.Text = 'Yaw/Heading (\degree)';
            app.YawHeadingDegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.YawHeadingDegreeGauge.Limits = [-180 180];
            app.YawHeadingDegreeGauge.MajorTicks = [-180 -90 0 90 180];
            app.YawHeadingDegreeGauge.MajorTickLabels = {'-180', '-90', '0', '90', '180'};
            app.YawHeadingDegreeGauge.MinorTicks = [-170 -160 -150 -140 -130 -120 -110 -100 -80 -70 -60 -50 -40 -30 -20 -10 10 20 30 40 50 60 70 80 100 110 120 130 140 150 160 170];
            app.StopDataButton = uibutton(app.HomeTab, 'state');
            app.StopDataButton.Text = 'Stop Data';
            app.StartData = uibutton(app.HomeTab, 'push');
            app.StartData.ButtonPushedFcn = createCallbackFcn(app, @StartDataButtonPushed, true);
            app.StartData.Text = 'Start Data';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.TabGroup2 = uitabgroup(app.GraphsTab);
            app.WheelSpeedTab = uitab(app.TabGroup2);
            app.WheelSpeedTab.Title = 'Wheel Speed';
            app.WheelSpeedGraph = uiaxes(app.WheelSpeedTab);
            title(app.WheelSpeedGraph, 'Wheel Speed vs Time')
            xlabel(app.WheelSpeedGraph, 'dt')
            ylabel(app.WheelSpeedGraph, 'RPM')
            zlabel(app.WheelSpeedGraph, 'Z')
            app.VehicleSpeedTab = uitab(app.TabGroup2);
            app.VehicleSpeedTab.Title = 'Vehicle Speed';
            app.CellVoltageTab = uitab(app.TabGroup2);
            app.CellVoltageTab.Title = 'Cell Voltage';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SSHAddressEditFieldLabel = uilabel(app.SetupTab);
            app.SSHAddressEditFieldLabel.Text = 'SSH Address';
            app.SSHAddressEditField = uieditfield(app.SetupTab, 'text');
            app.SSHAddressEditField.Value = 'rcCar';
            app.SSHPasswordEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPasswordEditFieldLabel.Text = 'SSH Password';
            app.SSHPasswordEditField = uieditfield(app.SetupTab, 'text');
            app.SSHPasswordEditField.Value = 'admin';
            app.SSHPortNumberEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPortNumberEditFieldLabel.Text = 'SSH Port Number';
            app.SSHPortNumberEditField = uieditfield(app.SetupTab, 'numeric');
            app.SSHCarNameEditFieldLabel = uilabel(app.SetupTab);
            app.SSHCarNameEditFieldLabel.Text = 'SSH Car Name';
            app.SSHCarNameEditField = uieditfield(app.SetupTab, 'numeric');
            app.GPSFixLampLabel = uilabel(app.SetupTab);
            app.GPSFixLampLabel.Text = 'GPS Fix';
            app.GPSFixLamp = uilamp(app.SetupTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end